classdef App_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EBreaverUIFigure                matlab.ui.Figure
        CreateanalysisobjectButton      matlab.ui.control.Button
        OpenREAVERGUIforsegmentationButton  matlab.ui.control.Button
        BrowseTestButton                matlab.ui.control.Button
        BrowseControlButton             matlab.ui.control.Button
        ProcessfoldersButton            matlab.ui.control.Button
        perivascularwidthpxSpinner      matlab.ui.control.Spinner
        perivascularwidthpxSpinnerLabel  matlab.ui.control.Label
        TestdirectoryEditField          matlab.ui.control.EditField
        TestdirectoryEditFieldLabel     matlab.ui.control.Label
        ControldirectoryEditField       matlab.ui.control.EditField
        ControldirectoryEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProcessfoldersButton
        function ProcessfoldersButtonPushed(app, event)
            control_dir = app.ControldirectoryEditField.Value;
            test_dir = app.TestdirectoryEditField.Value;
            n_px = app.perivascularwidthpxSpinner.Value;
            EB_analysis_entire_folder(n_px,control_dir,0);
            EB_analysis_entire_folder(n_px,test_dir,0);            
        end

        % Button pushed function: BrowseControlButton
        function BrowseControlButtonPushed(app, event)
            control_dir = uigetdir(pwd,'Choose control directory');
            app.ControldirectoryEditField.Value = control_dir;
        end

        % Button pushed function: BrowseTestButton
        function BrowseTestButtonPushed(app, event)
            test_dir = uigetdir(pwd,'Choose test directory');
            app.TestdirectoryEditField.Value = test_dir;
        end

        % Button pushed function: OpenREAVERGUIforsegmentationButton
        function OpenREAVERGUIforsegmentationButtonPushed(app, event)
            REAVER_GUI;
        end

        % Button pushed function: CreateanalysisobjectButton
        function CreateanalysisobjectButtonPushed(app, event)
            results = EB_analysis;
            assignin("base","results",results);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EBreaverUIFigure = uifigure('Visible', 'off');
            app.EBreaverUIFigure.Name = 'EB reaver';
            app.ControldirectoryEditFieldLabel = uilabel(app.EBreaverUIFigure);
            app.ControldirectoryEditFieldLabel.Text = 'Control directory';
            app.ControldirectoryEditField = uieditfield(app.EBreaverUIFigure, 'text');
            app.TestdirectoryEditFieldLabel = uilabel(app.EBreaverUIFigure);
            app.TestdirectoryEditFieldLabel.Text = 'Test directory';
            app.TestdirectoryEditField = uieditfield(app.EBreaverUIFigure, 'text');
            app.perivascularwidthpxSpinnerLabel = uilabel(app.EBreaverUIFigure);
            app.perivascularwidthpxSpinnerLabel.Text = 'perivascular width [px]';
            app.perivascularwidthpxSpinner = uispinner(app.EBreaverUIFigure);
            app.ProcessfoldersButton = uibutton(app.EBreaverUIFigure, 'push');
            app.ProcessfoldersButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessfoldersButtonPushed, true);
            app.ProcessfoldersButton.Text = 'Process folders';
            app.BrowseControlButton = uibutton(app.EBreaverUIFigure, 'push');
            app.BrowseControlButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseControlButtonPushed, true);
            app.BrowseControlButton.Text = 'Browse';
            app.BrowseTestButton = uibutton(app.EBreaverUIFigure, 'push');
            app.BrowseTestButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseTestButtonPushed, true);
            app.BrowseTestButton.Text = 'Browse';
            app.OpenREAVERGUIforsegmentationButton = uibutton(app.EBreaverUIFigure, 'push');
            app.OpenREAVERGUIforsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @OpenREAVERGUIforsegmentationButtonPushed, true);
            app.OpenREAVERGUIforsegmentationButton.Text = 'Open REAVER GUI for segmentation';
            app.CreateanalysisobjectButton = uibutton(app.EBreaverUIFigure, 'push');
            app.CreateanalysisobjectButton.ButtonPushedFcn = createCallbackFcn(app, @CreateanalysisobjectButtonPushed, true);
            app.CreateanalysisobjectButton.Text = 'Create analysis object';
            % Show the figure after all components are created
            app.EBreaverUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EBreaverUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EBreaverUIFigure)
        end
    end
end